classdef FlyGroomA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FlyGroomAFlyGroomingAnalysisLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        BoxplotsTab                     matlab.ui.container.Tab
        SavePathEditField               matlab.ui.control.EditField
        SavePathEditFieldLabel          matlab.ui.control.Label
        SetButton                       matlab.ui.control.Button
        MetricBox                       matlab.ui.control.Label
        TotalTimeCheckBox               matlab.ui.control.CheckBox
        PercentTimeCheckBox             matlab.ui.control.CheckBox
        BoutDurationCheckBox            matlab.ui.control.CheckBox
        BoutFrequencyCheckBox           matlab.ui.control.CheckBox
        PlotBox                         matlab.ui.control.Button
        ExportdatatoExcelCheckBox       matlab.ui.control.CheckBox
        GraphDropDown                   matlab.ui.control.DropDown
        GraphDropDownLabel              matlab.ui.control.Label
        PlotAbsDifffromControlCheckBox  matlab.ui.control.CheckBox
        BinningDropDown                 matlab.ui.control.DropDown
        BinningDropDownLabel            matlab.ui.control.Label
        ControlDropDown                 matlab.ui.control.DropDown
        ControlDropDownLabel            matlab.ui.control.Label
        UpdatematfilesCheckBox          matlab.ui.control.CheckBox
        GroupingDropDown                matlab.ui.control.DropDown
        GroupingDropDownLabel           matlab.ui.control.Label
        StatisticsDropDown              matlab.ui.control.DropDown
        StatisticsDropDownLabel         matlab.ui.control.Label
        HistogramsTab                   matlab.ui.container.Tab
        PlotHistogram                   matlab.ui.control.Button
        StimulusPanel                   matlab.ui.container.Panel
        removeStim                      matlab.ui.control.Button
        addStim                         matlab.ui.control.Button
        StimulusColorLabel              matlab.ui.control.Label
        StimColorLamp                   matlab.ui.control.Lamp
        StimBEditField                  matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        StimGEditField                  matlab.ui.control.NumericEditField
        GEditFieldLabel                 matlab.ui.control.Label
        StimREditField                  matlab.ui.control.NumericEditField
        REditFieldLabel                 matlab.ui.control.Label
        StimulustimessecLabel           matlab.ui.control.Label
        stimtimes                       matlab.ui.control.Table
        PlotStimulusCheckBox            matlab.ui.control.CheckBox
        SmoothingsecEditField           matlab.ui.control.NumericEditField
        SmoothingsecEditFieldLabel      matlab.ui.control.Label
        FrameRatefpsEditField           matlab.ui.control.NumericEditField
        FrameRatefpsEditFieldLabel      matlab.ui.control.Label
        EthogramsTab                    matlab.ui.container.Tab
        StimBEditField_etho             matlab.ui.control.NumericEditField
        BEditFieldLabel_2               matlab.ui.control.Label
        StimGEditField_etho             matlab.ui.control.NumericEditField
        GEditFieldLabel_2               matlab.ui.control.Label
        StimREditField_etho             matlab.ui.control.NumericEditField
        REditFieldLabel_2               matlab.ui.control.Label
        StimColorLamp_etho              matlab.ui.control.Lamp
        StimulusColorLabel_etho         matlab.ui.control.Label
        PlotStimulusCheckBox_etho       matlab.ui.control.CheckBox
        EthogramtypeDropDown            matlab.ui.control.DropDown
        EthogramtypeDropDownLabel       matlab.ui.control.Label
        PlotEthogram                    matlab.ui.control.Button
        MatFilesPanel                   matlab.ui.container.Panel
        ProcesstxtFilesButton           matlab.ui.control.Button
        RemovefilesButton               matlab.ui.control.Button
        FilesListBox                    matlab.ui.control.ListBox
        FilesListBoxLabel               matlab.ui.control.Label
        AddfilesButton                  matlab.ui.control.Button
        BehaviorsListBox                matlab.ui.control.ListBox
        BehaviorsListBoxLabel           matlab.ui.control.Label
        TimeRangesecLabel               matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartLabel                      matlab.ui.control.Label
        GenotypeEditField               matlab.ui.control.EditField
        GenotypeEditFieldLabel          matlab.ui.control.Label
        PlotselectionPanel              matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        TimeRangesecLabel_2             matlab.ui.control.Label
        CommonEndLabel                  matlab.ui.control.Label
        CommonEnd                       matlab.ui.control.NumericEditField
        CommonStartLabel                matlab.ui.control.Label
        CommonStart                     matlab.ui.control.NumericEditField
        LoadParamsButton                matlab.ui.control.Button
        EditParamsButton                matlab.ui.control.Button
        BehaviorstoplotList             matlab.ui.control.ListBox
        BehaviorstoplotListBoxLabel     matlab.ui.control.Label
        GenotypestoplotList             matlab.ui.control.ListBox
        GenotypestoplotListBoxLabel     matlab.ui.control.Label
    end


    properties (Access = public)
        data    % data from matfiles the user has selected
        matIdx  % index of currently selected mat file
        files   % files to analyze
        genotypes % genotypes of selected experiments
        genos2plot  % genotypes to plot
        genoIdx     % index to pull genotypes to plot from data
        behaviors % common behaviors
        behavs2plot % behaviors to plot
        behavIdx    % index to pull behaviors to plot from data
        timerange   % start and end of time range to plot (sec)
        timerangelimit  % common time range for all experiments (sec)
        plotgrouping = 'Behavior';      % how to group data in plots
        plotcontrol = 'None';        % control to use for plots
        graphtype       % type of graph to plot (box or bar)
        stats           % what kind of stats to do (para, non-para, none)
        plotbinning = 'Total';     % whether to plot total data or binned data
        plotmetrics = {};     % what metric to plot (percent time, total time, bout frequency, bout duration)
        updatemat       % update mat files
        absdiff         % plot absdiff from control
        export2excel    % export data to excel
        plotstim         % plot stimuli on Histogram
        stimcolor = [0 0 0]      % color of stimulation bars
        stimtimes_all       % Start and dur (sec) of stimulations times across all files
        stimtimes_single    % Start and dur (sec) of stimulations times for a single expt
        fps             % frames per minute
        win  % window for smooting histograms
        behaviorClasses % behavior classes defined from param file
        behavTable      % table of behaviors and colors from param file
        behavList       % list of behaviors defined from param file
        ethogram_type = 'Compact';  % style of ethogram to plot (compact, expanded, initial bout)
        xlsSavePath = '' % Path where xls file will be saved
    end

    methods (Access = private)

        function update_matfile_info(app)
            % update genotype field
            app.GenotypeEditField.Value = app.data{app.matIdx}.genotype;

            % update Time range
            time_range = app.data{app.matIdx}.timeRange;
            app.StartEditField.Value = time_range(1);
            app.EndEditField.Value = time_range(2);

            % update Behaviors
            behaviors = fieldnames(app.data{app.matIdx}.events);

            behaviors = behaviors(strcmp(behaviors,'file_path')==0); % remove 'file_path' from list of behaviors
            app.BehaviorsListBox.Items = behaviors;

        end

        function updatecommonfeats(app)
            % This function updates all common features in boxplot tab from
            % data files

            % get common behaviors
            behaviors = fieldnames(app.data{1}.events);
            for i = 2:length(app.data)
                behaviors = intersect(behaviors, fieldnames(app.data{i}.events));
            end

            if ~isempty(ismember({'file_path' 'stimulus_on'},behaviors))
                keepIdx = ones(1,length(behaviors));
                keepIdx(strcmp('file_path',behaviors)) = 0;
                keepIdx(strcmp('stimulus_on',behaviors)) = 0;
                keepIdx = logical(keepIdx);
                behaviors = behaviors(keepIdx);
            end
            app.behaviors = behaviors;
            app.BehaviorstoplotList.Items = behaviors; % update possible behaviors in boxplots
            %app.BehaviorstoplotListHist.Items = behaviors; % update possible behaviors in histogram

            % update list of genotypes to plot and stim times
            genos = cell(1,length(app.data));
            %stimtimes = cell(length(app.data),2);
            for i = 1:length(app.data)
                genos{i} = app.data{i}.genotype;
                %stimtimes(i,1) = app.data{i}.timeRange;
            end

            app.genotypes = genos;
            app.GenotypestoplotList.Items = app.genotypes;
            %app.GenotypestoplotListHist.Items = app.genotypes;

            % find minimum time ranges across files
            %             app.timerange = [min(common_timerange(:,1)) min(common_timerange(:,2))];
            app.CommonStart.Value = app.timerange(1);
            %app.CommonStart_2.Value = app.timerange(1);
            app.CommonEnd.Value = app.timerange(2);
            %app.CommonEnd_2.Value = app.timerange(2);

            % update controlgenoslist
            app.ControlDropDown.Items = ['None' app.genotypes];

            % get common stimulation times


        end

        function out = matrixconversion(app,events,behaviors)

            % Inputs
            % events = structure with grooming data

            % fps = frames per second (e.g. 30 fps)
            fps = app.fps;

            % behaviors = behaviors to convert.  Must be same as fieldnames of events
            % structure

            % check to see if behaviors is a cell array or string, if string convert to
            % cell array
            if ~iscell(behaviors)
                behaviors = cellstr(behaviors);
            end

            % get duration in frames
            % duration
            app.timerange;
            timeduration = app.timerange(2)-app.timerange(1);
            numpoints = timeduration * app.fps;

            %need to specify how many rows based on number of flies
            num_flies=size(events, 2);

            % create struct for output
            out = struct;

            for b = 1:length(behaviors)
                %create matrix of flies (rows) by number of time points (columns)
                behav_mat=zeros(num_flies,numpoints);

                for h=1:num_flies
                    data=(events(h).(behaviors{b}));

                    numbouts=size(data,1);
                    timecorrection=min(events(h).stimulus_on(:,1))-20;
                    %Note: I think the time correction was something put in
                    %assuming all stimuli start at 20 seconds. Better to
                    %have user input stimuli times and use this as
                    %correction.
                    %timecorrection = 0;

                    for i=1:numbouts
                        %initpoint=ceil(data(i,1)/binningduration);
                        initpoint=round((data(i,1)-timecorrection)*fps);
                        %initpoint=round(app.timerange(1) * app.fps);
                        if initpoint==0
                            initpoint=1;
                        end
                        %finalpoint=initpoint + round((data(i,2))*fps);
                        finalpoint = round((data(i,1)+data(i,2)-timecorrection) * app.fps);
                        if finalpoint > length(behav_mat)
                            finalpoint = length(behav_mat);
                        end
                        if initpoint>0
                            behav_mat(h,initpoint:finalpoint)=1;
                        end
                    end

                end

                out.(behaviors{b}) = behav_mat;

            end
        end

        function plothistogram(app)
            %BEHAV_HISTOGRAMS Summary of this function goes here
            %   Detailed explanation goes here

            % pull genotypes2plot
            %             genos2plot = app.genos2plot;
            %             genoIdx = app.genoIdx;

            % pull behaviors2plot
            %             behavs2plot = app.behavs2plot;
            %             behavIdx = app.behavIdx;

            % duration of time to plot (i.e., timerange)
            %timerange = app.timerange; %seconds
            dur = app.timerange(2)-app.timerange(1);

            % fps
            fps = app.FrameRatefpsEditField.Value;

            % smooting window
            win = app.SmoothingsecEditField.Value;

            %% Define colors for plots

            %make sure selected behaviors are in behav_params file
            possible_behavs = [unique(app.behavTable.BehaviorClass) ; unique(app.behavTable.Behavior)];
            foundbehavs = ismember(app.behavs2plot,possible_behavs);
            % add message to ask user to edit file to add this behavior

            % put together colors for each behavior to plot
            colors2plot = {};
            existing_behavs = app.behavs2plot(foundbehavs);
            for i = 1:length(existing_behavs)
                idx = find(strcmp(app.behavList(:,1),existing_behavs{i})==1);
                colors2plot{i,1} = existing_behavs{i};
                colors2plot{i,2} = app.behavList{idx,2};
            end


            %% Plot each genotype

            for i = 1:length(app.genos2plot)

                events = app.data{app.genoIdx(i)}.events;
                genotypename = app.genos2plot{i};

                behav_mat = matrixconversion(app,events,app.behavs2plot);


                % get stimuli & offset
                if app.plotstim==1
                    stim_mat = matrixconversion(app,events,'stimulus_on');
                    stim = stim_mat.stimulus_on;
                    %                     stim1 = zeros(size(stim,1),1);
                    %                     for s = 1:size(stim,1)
                    %                         stim1(s) = find(stim(i,:)==1,1);
                    %                     end
                    %                     offset = min(stim1)-1;
                    %
                    %
                    %                     stim_corr = zeros(size(stim));
                    %                     for s = 1:size(stim1,1)
                    %                         temp = stim(s,stim1(i)-offset(i):end);
                    %                         stim_corr(i,1:length(temp)) = temp;
                    %                     end


                    stim = sum(stim,1);
                    %stim = sum(stim_corr,1);
                    bw = bwconncomp(stim);

                    stim_times = zeros(length(bw.PixelIdxList),2);

                    for s = 1:size(stim_times,1)
                        stim_times(s,:) = [bw.PixelIdxList{s}(1) bw.PixelIdxList{s}(end)];
                    end
                else stim_times = [];
                end


                openFigHandles = findall(0, 'type', 'figure');
                numOpenFigures = numel(openFigHandles);

                figure(numOpenFigures)

                % plot time series histogram for each behavior
                for b = 1:length(app.behavs2plot)
                    idx = find(strcmp(colors2plot(:,1),app.behavs2plot{b})==1);
                    plotcolor = colors2plot{idx,2};
                    stimcolor = app.stimcolor;
                    if ~isempty(stim_times)
                        if b ==length(app.behavs2plot)
                            %                         hoopfer_timeseries_hist_min(behav_mat,behavs2plot{b},app.win,1/app.fps,genotypename,plotcolor,'line',[0 dur],stim_times/fps,stimcolor,'')
                            plot_timeserieshist(app,behav_mat,app.behavs2plot{b},win,1/fps,genotypename,plotcolor,'line',[0 dur],stim_times/fps,stimcolor,'')
                        else
                            plot_timeserieshist(app,behav_mat,app.behavs2plot{b},win,1/fps,genotypename,plotcolor,'line',[0 dur],'','','')
                        end
                        hold on

                    else

                        plot_timeserieshist(app,behav_mat,app.behavs2plot{b},win,1/fps,genotypename,plotcolor,'line',[0 dur],'','','')
                    end
                    hold on

                end
                legend(app.behavs2plot);
            end


        end

        function [behaviorClasses, behavTable, behavList] = import_behavparams(dirName,fileName)
            %IMPORT_BEHAVPARAMS This function imports behaviors and colors for grooming
            %plots
            %  Detailed explanation goes here
            % dirName = filepath where csv param file is located
            % fileName = name of csv params file to import


            %% Import csv file and make struct

            T = readtable(fullfile(dirName,fileName));

            T.BehaviorClass = categorical(T.BehaviorClass);

            % get BehaviorClasses and create structure
            behav_classes = cellstr(unique(T.BehaviorClass));    % behavior classes from 1st col

            % reorder behav_classes as specified in ClassOrder
            T = sortrows(T,{'ClassOrder','BehaviorOrder'},{'ascend','ascend'}); % sort rows by class and behavior

            order_ind = [];
            for i = 1:length(behav_classes)
                order_ind = [order_ind unique(T{T.BehaviorClass==behav_classes{i},'ClassOrder'})];
            end
            [order_ind,idx] = sort(order_ind);
            behav_classes = behav_classes(idx);


            % % Behavior Classes (this will be imported from a params file in future)
            %     behaviorClasses.head_cleaning = {'head_cleaning', 'antennal_cleaning', 'proboscis_cleaning', 'ventral_head'};
            %     behaviorClasses.front_leg_rubbing = {'front_leg_rubbing', 'b_1st_and_2nd_leg_rub', 'front_proximal_leg_rub'};
            %     behaviorClasses.wing_cleaning = {'wing_cleaning', 'ventral_wing', 'wing_blade', 'wing_hinge_slash_haltere'};
            %     behaviorClasses.back_leg_rubbing = {'back_leg_rubbing', 'proximal_leg_rub', 'b_2nd_and_3rd_leg_rub'};
            %     behaviorClasses.abdominal_cleaning = {'abdominal_cleaning', 'genital_cleaning'};
            %     behaviorClasses.other = {'ventral_front_cleaning', 'grooming_attempt', 'first_leg_notum', 'ventral_back_cleaning', ...
            %                              'back_leg_grooming_attempt', 'standing_still', 'on_back', 'slip_slash_jump', 'ventral_middle'};

            behaviorClasses = struct;
            behavList = {};

            for i = 1:length(behav_classes)
                bIdx =  find(T.BehaviorClass==behav_classes{i}==1);
                if ~isempty(bIdx)
                    % get behaviors for this class
                    behavs = T.Behavior(bIdx);                                               % get behaviors belonging to this class
                    [~,bind] = sort(T.BehaviorOrder(bIdx));                                  % get and sort behavior order
                    behavs = behavs(bind);                                                   % reorder behaviors according to specified order
                    behavs = reshape(behavs,1,size(behavs,1));                               % reshape into 1x... array

                    % get colors for individual behaviors
                    classcolor = [T.R1(bIdx(1)) T.G1(bIdx(1)) T.B1(bIdx(1))];
                    behavcolors = [T.R2(bIdx) T.G2(bIdx) T.B2(bIdx)];
                    behavcolors = behavcolors(bind,:);  % sort to match behaviors

                    behaviorClasses.(behav_classes{i}).classcolor =  classcolor;
                    behaviorClasses.(behav_classes{i}).behaviors = behavs;
                    behaviorClasses.(behav_classes{i}).behaviorcolors = behavcolors;

                    blist{1,1} = behav_classes{i};
                    blist{1,2} = classcolor;
                    blist(2:1+length(behavs),1) = behavs';
                    for ii = 1:length(behavs)
                        blist{ii+1,2} = behavcolors(ii,:);
                    end

                    behavList = [behavList ; blist];

                end

            end


            behavTable = T;
        end

        function plot_timeserieshist(app,behav_mat,behavior,bin,frmRate,plot_title,color,type,time_lim,stimtimes,stimcolor,fly)

            % TIMESERIES_HIST plots a histogram of the fraction of flies showing a
            % behavior.  The data can be binned (bin = seconds).
            %hoopfer_timeseries_hist_min(out,'antennal_cleaning',1,1/35,'',[1 0 0],'line',[0 35],[],[],[])

            if nargin < 8 || isempty(time_lim)
                time_lim = [];
            end

            if nargin < 9
                stimtimes = [];
                stimcolor = [];
            end


            % load behavior matrix
            X = behav_mat.(behavior);

            if ~isempty(time_lim)
                X = X(:,1+time_lim(1)/frmRate:time_lim(2)/frmRate,:);
            end

            binfrms = round(bin/frmRate);  % convert bin to frames

            if ~isempty(time_lim)
            end


            bins = 0:binfrms:size(X,2);    % calculate bins

            % concatenate flies together
            if nargin < 10 || isempty(fly) % plot one fly only?
                fly = 1:size(X,3);
            end

            all = [];
            for i = fly
                all = [all ; X(:,:,i)];
            end

            maxTime = round(size(X,2)*frmRate);

            % Sum the number of frames/bin that each fly is doing the behavior
            s = zeros(size(all,1),length(bins)-1);

            for i = 1:length(bins)-1
                s(:,i) = sum(all(:,bins(i)+1:bins(i+1)),2);
            end

            % Calculate the fraction of flies performing behavior per bin
            ind = s > 0;
            fraction = sum(ind,1) / size(all,1);

            xticks = round(bins * frmRate);
            % find minutes
            xticks_labels = xticks / 60; % convert to minutes
            xtick_ind = find(rem(xticks_labels,1)==0);
            xticks_labels = xticks_labels(xtick_ind);

            xticks = xticks(2:end);

            switch type
                case 'filled'
                    area(xticks,fraction,'FaceColor',color)
                case 'line'
                    plot(xticks,fraction,'color',color,'linewidth',2)
                case 'bar'
                    h = bar(xticks,fraction);
                    set(h,'facecolor',color);
            end

            % Get axis limits
            xlims = get(gca,'xlim');
            set(gca,'xlim',[0 maxTime]);
            set(gca,'ylim',[0 1]);
            ylims = get(gca,'ylim');

            % Add stimtimes
            % Add stimulation intervals (optional)
            if ~isempty(stimtimes)
                for i = 1:size(stimtimes,1)
                    %         h = patch([stimtimes(i,1) stimtimes(i,1) stimtimes(i,2) stimtimes(i,2)],...
                    %             [0 ylims(2)/50 ylims(2)/50 0],'facecolor',stimcolor,'EdgeColor','none');
                    a = rectangle('Position',[stimtimes(i,1),0,stimtimes(i,2)-stimtimes(i,1),ylims(2)],...
                        'FaceColor',[stimcolor 0.3],'EdgeColor','none');
                end
            end

            set(gca,'xtick',[xticks_labels * 60]);
            set(gca,'xticklabel',[xticks_labels]);
            set(gca,'fontsize',14)


            ylabel({'Fraction of flies'; 'performing behavior'},'fontsize',14,'interpreter','none')
            xlabel('Time (min)','fontsize',14)

            legend(app.behavs2plot,'interpreter','none');

            title(plot_title,'fontsize',14,'fontweight','b','interpreter','none')
            box('off')

        end


        function update_stimcolors(app)
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            app.StimREditField.Value = app.stimcolor(1);
            app.StimREditField_etho.Value = app.stimcolor(1);
            app.StimGEditField.Value = app.stimcolor(2);
            app.StimGEditField_etho.Value = app.stimcolor(2);
            app.StimBEditField.Value = app.stimcolor(3);
            app.StimBEditField_etho.Value = app.stimcolor(3);

        end % end of genotype loop


        function results = plot_ethogram(app)
            % PLOT_ETHOGRAM plots a raster graph of behaviors per fly.  Multiple
            % behaviors can be plotted, stimulation intervals can be added.

            % INPUTS
            % LIMITS: minTime and maxTime of x-axis.  If blank goes with 0:max time.
            % COLORs: 0 = go with default colors, 1 = user to define colors
            % STIMTIMES: matrix of stimulation times (Ex. LED light)
            %                Format: [start,dur] in seconds
            % CUSTOMIZE: boolean, true = ask user to select which behaviors to plot and
            % enter title for plot, false = go with defaults
            % SEPARATE: true = plot behaviors on separate lines, false = compress
            % behaviors to one line
            % FLY: specify which fly to plot, must be a number


            %% DEFINE INPUTS AND OPTIONS

            % pull genotypes2plot
            %             genos2plot = app.genos2plot;
            %             genoIdx = app.genoIdx;

            % pull behaviors2plot
            %             behavs2plot = app.behavs2plot;
            %             behavIdx = app.behavIdx;

            % duration of time to plot (i.e., timerange)
            dur = app.timerange(2)-app.timerange(1);

            % Frame rate of movies
            frmRate = 1 / app.fps;

            fileGroupCount = inf;

            %% DEFINE COLORS AND STYLE OF ETHOGRAM

            %make sure selected behaviors are in behav_params file
            possible_behavs = [unique(app.behavTable.BehaviorClass); unique(app.behavTable.Behavior)];
            notfound = ~ismember(app.behavs2plot,possible_behavs);
            excluded_behavs = app.behavs2plot(notfound);
            existing_behavs = app.behavs2plot(~notfound);
            nbehavs = length(existing_behavs); % Number of behaviors to plot

            % exclude missing behaviors and display  message to ask user to edit file to add this behavior
            if ~isempty(excluded_behavs)
                % remove the excluded behavior from apps.behavs2plot list
                for missing = 1:length(excluded_behavs)
                    msgbox(['The following behavior was not found in the file: ' excluded_behavs{missing}],"Error","error")
                end
            end

            % put together colors for each behavior to plot
            colors2plot = {};
            for i = 1:length(existing_behavs)
                idx = find(strcmp(app.behavList(:,1),existing_behavs{i})==1);
                colors2plot{i,1} = existing_behavs{i};
                colors2plot{i,2} = app.behavList{idx,2};
            end

            % Specify style and particular of ethogram
            % Compact: plot all selected behaviors on a line, each line is a
            % different fly
            % Expanded: plot each behavior on a separate line, plot only shows one
            % fly

            % Style
            style = lower(app.ethogram_type); % compact or expanded

            % if expanded is selected, user must select which flies to plot


            % Constants for ethogram plot (This could be specified in GUI)
            barHeight = 50;        % the height of each behavior's bar
            barSep = 20;             % the amount of vertical separation between each bar
            timePad = 2;   % the amount of padding to add to the left and right side of the plot (seconds)
            stimulusHeight = 6;
            stimulusSep = 6;


            %% Plot ethogram for each genotype

            for i = 1:length(app.genos2plot) % loop through each genotype selected
                % each genotype will be plotted on a separate figure

                % Get and organize data for each genotype

                events = app.data{app.genoIdx(i)}.events;
                genotypename = app.genos2plot{i};
                behav_mat = matrixconversion(app,events,existing_behavs);

                % Number of pairs to be plotted
                % Correct: for expanded plot
                nflies = size(behav_mat.(app.behavs2plot{1}),1);
                isMultiFile = nflies>1;

                % Convert Time Range to frames
                minFrms = app.timerange(1) * app.fps;
                maxFrms = app.timerange(2) * app.fps;

                % check to see that behav_mat has data in these ranges

                % Check to see if chosen plot style works with data
                %     style_options = {'compact', 'expanded'}; % can pull from GUI
                %
                %     if ~isMultiFile
                %
                %         if ~ismember(style, {'compact','expanded'})
                %             f = msgbox("")
                %         end
                %     else
                %         style = lower(style);
                %     end

                % get stimulation times
                if app.plotstim==1
                    stim_mat = matrixconversion(app,events,'stimulus_on');
                    stim = stim_mat.stimulus_on;
                    stim = sum(stim,1);
                    bw = bwconncomp(stim);

                    stim_times = zeros(length(bw.PixelIdxList),2);

                    for s = 1:size(stim_times,1)
                        stim_times(s,:) = [bw.PixelIdxList{s}(1) bw.PixelIdxList{s}(end)];
                    end

                    % correct for offset of stimulation
                end


                %% Create a new figure window.
                fig = figure('Position', [100 100 800 800]);
                halfBarHeight = barHeight / 2;
                halfBarSep = barSep / 2;


                %                 if strcmp(style, 'compact')
                %                     % Show the file numbers along the y axis for the compact style.
                %                     if isMultiFile
                %                         shiftedFileCount = npairs + floor((npairs - 1) / fileGroupCount);
                %                         set(gca, 'YLim', [-(stimulusHeight + stimulusSep), shiftedFileCount * (barHeight + barSep) + stimulusSep], ...
                %                             'YTick', halfBarHeight + halfBarSep:barHeight + barSep:npairs * (barHeight + barSep), ...
                %                             'TickDir', 'out', ...
                %                             'YTickLabel', shiftedFileCount:-1:1, ...
                %                             'TickLength', [0.001 0.001]);
                %                         plotHeight = shiftedFileCount * (barHeight + barSep) + stimulusSep;
                %                     else
                %                         set(gca, 'YTick', [], ...
                %                             'YLim', [-(stimulusHeight + stimulusSep), barHeight + stimulusSep]);
                %                         plotHeight = barHeight + stimulusSep;
                %                     end
                %                 else
                %                     % Show the behavior names along the y axis for the expanded or initial bout styles.
                %                     set(gca, 'YLim', [-(stimulusHeight + stimulusSep), length(behaviors) * (barHeight + barSep) + stimulusSep], ...
                %                         'YTick', halfBarHeight + halfBarSep:barHeight + barSep:length(behaviors) * (barHeight + barSep), ...
                %                         'YTickLabel', fieldToBehaviorName(behaviors(length(behaviors):-1:1)), ...
                %                         'TickDir', 'out', ...
                %                         'TickLength', [0.001 0.001]);
                %                     plotHeight = length(behaviors) * (barHeight + barSep) + stimulusSep;
                %                 end
                %                 set(gca, 'Box', 'off', ...
                %                     'DataAspectRatio', [1 1 1], ...
                %                     'Color', 'none', ...
                %                     'XLim', [minTime - timePad - timeOrigin, maxTime + timePad - timeOrigin] * 20, ...
                %                     'XTick', -6000*20:60*20:6000*20, ...
                %                     'XTickLabel', -6000:60:6000, ...
                %                     'Units', 'points')

                % pull data

                % Set x and y limits
                switch style
                    case 'compact'
                        % set ylim to be nflies*barheight + barsep*(nflies+1)
                        plotHeight = (barHeight*nflies) + barSep*(nflies+1);

                    case 'expanded'
                        plotHeight = (barHeight*nbehavs) + barSep*(nbehavs+1);

                end

                set(gca,'Ylim',[0 plotHeight])
                set(gca,'Xlim',[minFrms maxFrms])
                hold on

                xlims = get(gca,'xlim');
                ylims = get(gca,'ylim');

                % x axis labels
                minutes = (app.timerange(2)-app.timerange(1))/60;
                %minutes= maxTime/60;

                if minutes <= 5
                    xticklabels = 0:0.5:minutes;
                elseif minutes <= 10
                    xticklabels = 0:1:minutes;
                elseif minutes <= 30
                    xticklabels = 0:5:minutes;
                elseif minutes <= 60
                    xticklabels = 0:10:minutes;
                else xticklabels = 0:30:minutes;
                end

                % y axis labels depends on style


                % Set axis properties
                xticks = (xticklabels * 60) / frmRate;
                set(gca,'xtick',xticks)
                set(gca,'xticklabel',xticklabels)
                set(gca,'ytick',[])

                ylabel('Pairs ------->','fontsize',12)

                xlabel('Time (min)','fontsize',12)

                % create an object group for lines of the same behavior so
                % a legend can be created
                hg = hggroup;

                % Add stimulation intervals (optional)
                if app.plotstim==1
                    for s = 1:size(stim_times,1)
                        %             h = patch([stimtimes(i,1) stimtimes(i,1) stimtimes(i,2) stimtimes(i,2)],...
                        %                 [ylims(1) ylims(2) ylims(2) ylims(1)],stimcolor,'EdgeColor','none','FaceAlpha',1);
                        stimobj(s) = rectangle('Position',[stim_times(s,1),ylims(1),stim_times(s,2)-stim_times(s,1),ylims(2)],...
                            'FaceColor',app.StimColorLamp_etho.Color,'EdgeColor','none');
                    end
                end

                set(stimobj,'Parent',hg)
                set(hg,'Displayname','Stimulus')
                %legend(hg)

                % Align data to the stimulus


                % plotting data for compact ethogram


                % Loop through each behavior
                for b = 1:nbehavs
                    y1 = ylims(1)+barSep;
                    y2 = y1+barHeight;

                    % Loop through each fly
                    for f = 1:nflies

                        bw = bwconncomp(behav_mat.(existing_behavs{b})(f,:));

                        % Loop through each instance of behavior
                        for bb = 1:bw.NumObjects
                            x1 = bw.PixelIdxList{bb}(1);
                            x2 = bw.PixelIdxList{bb}(end);
                            hb(b) = rectangle('Position',[x1,y1,x2-x1+1,barHeight],...
                                'FaceColor',colors2plot{b,2},'EdgeColor',colors2plot{b,2},'linewidth',0.2);
                        end % instances

                        % increase y values
                        y1 = y2+barSep;
                        y2 = y1+barHeight;


                    end %flies

                end %behavior
                %hg = hggroup;
                %                 set(hb,'Parent',hg)
                %                 set(hb,'Displayname','')

                legend(hg)
                title(genotypename,'interpreter','none')


                % set ylim again
                %                 if separate
                %                     set(gca,'ylim',[0 c+1]);
                %                 end


            end % genotype


        end % plot_ethogram

        function T = data2table(app)

            % create cell array for temp storage of data
            cols = 4 + numel(app.plotmetrics);
            genos = {};
            behaviors = {};
            fly = [];
            bin = [];
            file_path = {};
            PT = [];
            TT = [];
            Fbouts = [];
            Dbouts = [];


            % specify variables
            Tvars = {'Genotype','Behavior','Fly','Bin',app.plotmetrics,'File_Path'};

            % parse the bins
            if contains(app.plotbinning,'Total')
                bins = app.timerange * app.fps;
            else
                % bins specified by user

            end

            %'bins' = timebins in which to divide data. MUST BE IN FRAMES!
            %       Format: [start1,end1; start2,end2]

            % Loop through each genotype
            for i = 1:length(app.genos2plot)

                events = app.data{app.genoIdx(i)}.events;
                genotypename = app.genos2plot{i};

                % Convert data for genotype to a behavior matrix
                behav_mat = matrixconversion(app,events,app.behavs2plot);

                % Loop through each behavior
                for b = 1:length(app.behavs2plot)
                    behav = app.behavs2plot{b};

                    % Loop through each time bin
                    for bb = 1:numel(bins)-1
                        binrange = bins(bb)+1:bins(bb+1);

                        % Calculate each metric and add to cell array
                        percent_time = (sum(behav_mat.(behav)(:,binrange),2)/length(behav_mat.(behav)(:,binrange)))*100;

                        total_time = sum(behav_mat.(behav)(:,binrange),2)/app.fps; % tot

                        nflies = size(behav_mat.(behav),1);

                        %bout_frequency & bout_dur
                        freq = []; dur = []; fpath = {};

                        % loop through each fly
                        for f = 1:nflies
                            bouts = bwconncomp(behav_mat.(behav)(f,binrange));
                            nbouts = bouts.NumObjects;
                            freq(f) = nbouts/(length(binrange)/app.fps/60); % nbouts/minute

                            d = zeros(nbouts,1);
                            for ii = 1:nbouts
                                d(ii) = length(bouts.PixelIdxList{ii})/app.fps; % duration in second
                            end
                            dur(f) = mean(d); % mean duration of bouts

                            fpath = [fpath ; events(f).file_path];

                        end % flies loop
                        % put data in temp arrays, these will grow each
                        % loop

                        gtemp(1:nflies,1) = {genotypename};
                        genos = [genos ; gtemp];

                        btemp(1:nflies,1) = {behav};
                        behaviors = [behaviors ; btemp];

                        ftemp(1:nflies,1) = 1:nflies;
                        fly = [fly ; ftemp];

                        bintemp(1:nflies,1) = bb; % change to bin_name later
                        bin = [bin ; bintemp];

                        file_path = [file_path ; fpath];

                        PT = [PT ; percent_time];
                        TT = [TT ; total_time];
                        Fbouts = [Fbouts ; freq'];
                        Dbouts = [Dbouts ; dur'];

                    end % behaviors loop

                end  % time bin loop

            end % genotype loop

            % create table

            T = table(categorical(genos),categorical(behaviors),categorical(fly),categorical(bin),PT,TT,Fbouts,Dbouts,file_path);
            T.Properties.VariableNames = {'Genotype','Behavior','Fly','Bin','Percent_time','Total_time','Freq','Dur','File_Path'};

        end

        function bxcharts = plotboxchart(app,plotdata)

            set(0,'DefaultTextInterpreter','none'); % set default interpreter so that underscores are written
            set(groot, 'DefaultAxesTickLabelInterpreter', 'none');

            % METRICS TO PLOT
            met2plot = app.plotmetrics;
            nplots = numel(met2plot);

            % GROUP DATA
            grouping = app.plotgrouping;
            groups = unique(plotdata.(grouping));
            nsubplots = numel(groups);
            if contains(grouping,'Behavior')
                subgroup = 'Genotype';
            else subgroup = 'Behavior';
            end

            % COLORS
            behavcols = cellstr(unique(plotdata.Behavior));
            for i = 1:length(behavcols)
                cidx = find(matches(app.behavList(:,1),behavcols{i})==1,1);
                behavcols(i,2) = app.behavList(cidx,2);
            end


            % MAKE A PLOT FOR EACH GROUP


            % loop through metric
            for m = 1:nplots
                % create figure
                openFigHandles = findall(0, 'type', 'figure');
                numOpenFigures = numel(openFigHandles);

                f(numOpenFigures) = figure('Name',met2plot{m});

                % DATA2EXCEL
                data2write = {}; % col1 is the name of the chart, col2 is the data from the plot

                % loop through groups
                for g = 1:nsubplots

                    % make a subtable of data for group
                    subplot_title = cellstr(groups(g));
                    subplot_title = subplot_title{:};
                    idx = plotdata.(grouping)==groups(g);
                    sub = plotdata(idx,:);
                    data2write{g,1} = subplot_title;
                    data2write{g,2} = sub(:,{'Genotype','Behavior','Fly','Bin',met2plot{m},'File_Path'});
                    %
                    % sort data by control if specified


                    % create subplots
                    subplot(nsubplots,1,g);
                    switch grouping
                        case 'Behavior'
                            h(g) = boxchart(sub.(subgroup),sub.(met2plot{m}));
                            % pull color of current behavior and apply to all boxes
                            colidx = find(matches(behavcols(:,1),cellstr(groups(g)))==1);
                            h(g).BoxFaceColor = behavcols{colidx,2};
                            h(g).WhiskerLineColor = behavcols{colidx,2};
                            h(g).MarkerColor = behavcols{colidx,2};
                            ylabel(met2plot{m})
                            title(subplot_title)


                        case 'Genotype'
                            hold on
                            behavs = cellstr(unique(sub.(subgroup)));
                            for ii = 1:length(behavs)
                                % pull color of all plotted behaviors and apply to each
                                colidx = find(matches(behavcols(:,1),behavs(ii))==1);
                                ydata = sub(sub.(subgroup)==behavs{ii},met2plot{m});
                                ydata = ydata.(met2plot{m});
                                xdata = repmat(ii,size(ydata,1),1);
                                h(g) = boxchart(xdata,ydata);
                                h(g).BoxFaceColor = behavcols{colidx,2};
                                h(g).WhiskerLineColor = behavcols{colidx,2};
                                h(g).MarkerColor = behavcols{colidx,2};
                            end
                            xticks(1:length(behavs));
                            xticklabels(behavs');
                            ylabel(met2plot{m});
                            title(subplot_title);

                    end

                end % groups (subplots)
                if app.export2excel
                    t = datetime('now', 'Format','yyyy_MM_dd HH:mm');
                    datestring = datestr(t,'YYmmDDHHMM');
                    
                    Fname = ['Figure_' num2str(f(numOpenFigures).Number) '_' f(numOpenFigures).Name '_' datestring '.xlsx'];
                    
                    if ~isempty(app.xlsSavePath)
                        Fname = fullfile(app.xlsSavePath,Fname);
                    end
                    
                    for w = 1:nsubplots
                        warning('off','MATLAB:xlswrite:AddSheet'); %optional
                        writetable(data2write{w,2},Fname,'Sheet',w);
                    end
                end
            end % metrics (figures)


        end

        function reset_GUI(app)
            app.data = [];
            app.GenotypeEditField.Value = '';

            app.FilesListBox.Items = {};

            app.behaviors = [];
            app.BehaviorsListBox.Items = {};

            app.StartEditField.Value = 0;
            app.EndEditField.Value = 0;
            app.timerange = [];
            app.timerangelimit = [];

            app.genotypes = {};
            app.GenotypestoplotList.Items = {};
            app.CommonStart.Value = 0;
            app.CommonEnd.Value = 0;
            app.BehaviorstoplotList.Items = {};

            app.ControlDropDown.Items = {'None'};


        end
    end

    methods (Access = public)

        function behaviors = getcommonbehaviors(app)

            behaviors = fieldnames(app.data{1}.events);
            for i = 2:length(app.data)
                behaviors = intersect(behaviors, fieldnames(app.data{i}.events));
            end

            if ~isempty(ismember({'file_path' 'stimulus_on'},behaviors))
                keepIdx = ones(1,length(behaviors));
                keepIdx(strcmp('file_path',behaviors)) = 0;
                keepIdx(strcmp('stimulus_on',behaviors)) = 0;
                keepIdx = logical(keepIdx);
                behaviors = behaviors(keepIdx);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.matIdx = 1;
            app.fps = 30;
            app.win = 1;
        end

        % Button pushed function: AddfilesButton
        function AddfilesButtonPushed(app, event)
            % Prompt the user for the file(s) to use.
            [fileNames, dirName] = uigetfile({'*.mat','Compiled grooming files (*.mat)'}, 'Pick one or more grooming files', 'MultiSelect', 'on');
            if isscalar(fileNames)
                return % the user cancelled
            end
            if iscell(fileNames)
                filePaths = {};
                for fileName = fileNames
                    filePaths = [filePaths fullfile(dirName, char(fileName))]; %#ok<AGROW>
                end
                filePaths = sort(filePaths);
            else
                filePaths = {fullfile(dirName, fileNames)};
                fileNames = {fileNames};
            end

            % Don't add mat files that are already in the list
            fileNames2remove = ones(1,length(fileNames));
            if ~isempty(app.FilesListBox.Items)
                for i = 1:length(fileNames) % check if each file name exists
                    if ismember(fileNames{i},app.FilesListBox.Items)
                        fileNames2remove(i) = 0;
                    end
                end
                fileNames2remove = logical(fileNames2remove);
                fileNames = fileNames(fileNames2remove);
            end

            % load mat files and store in ItemData
            ItemData = {};
            genotypes = {};

            for i = 1:length(filePaths)
                ItemData{i} = load(filePaths{i});
                genotypes{i} = ItemData{i}.genotype;
            end

            app.data = [app.data ItemData]; % update data
            app.FilesListBox.Items = [app.FilesListBox.Items  fileNames]; % add to list of filenames
            app.GenotypestoplotList.Items = [app.GenotypestoplotList.Items genotypes]; % add to list of genotypes

            update_matfile_info(app); % update fields pertaining to mat file

            % update timerange
            common_timerange = zeros(length(app.data),2);
            for i = 1:length(app.data)
                genos{i} = app.data{i}.genotype;
                common_timerange(i,:) = app.data{i}.timeRange;
            end
            app.timerangelimit = [min(common_timerange(:,1)) min(common_timerange(:,2))];
            app.timerange(1) = app.timerangelimit(1);
            app.timerange(2) = app.timerangelimit(2);

            % update other common fields
            updatecommonfeats(app);

            %             behaviors = getcommonbehaviors(app); % get common behaviors from all files
            %             app.BehaviorstoplotList.Items = behaviors; % update behaviors to plot
            %            app.ControlDropDown.Items = [app.ControlDropDown.Items app.GenotypestoplotList.Items]; % update Control dropdown
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)

            value = app.FilesListBox.Value;
            app.matIdx = find(strcmp(event.Source.Value,app.FilesListBox.Items)==1); % Update index of which file is selected
            update_matfile_info(app);

        end

        % Value changed function: GenotypeEditField
        function GenotypeEditFieldValueChanged(app, event)
            value = app.GenotypeEditField.Value;
            app.data{app.matIdx}.genotype = value;
            updatecommonfeats(app);
            %app.GenotypestoplotList.Items{app.matIdx} = value;
        end

        % Button pushed function: RemovefilesButton
        function RemovefilesButtonPushed(app, event)
            % Get names of files to be removed
            app.matIdx

            % Remove names from list (how to deal with only one file?)

            if length(app.matIdx)==length(app.data)
                reset_GUI(app);
            else
                % create index of files to eliminate
                keepers = ones(1,length(app.data));
                keepers(app.matIdx) = 0;
                keepers = logical(keepers);

                % Remove file data from ItemData

                app.data = app.data(keepers); % update data
                app.FilesListBox.Items = app.FilesListBox.Items(keepers); % update list of filenames
                app.GenotypestoplotList.Items = app.GenotypestoplotList.Items(keepers); % update list of genotypes

                update_matfile_info(app); % update fields pertaining to mat file

                % update timerange
                common_timerange = zeros(length(app.data),2);
                for i = 1:length(app.data)
                    genos{i} = app.data{i}.genotype;
                    common_timerange(i,:) = app.data{i}.timeRange;
                end
                app.timerangelimit = [min(common_timerange(:,1)) min(common_timerange(:,2))];
                app.timerange(1) = app.timerangelimit(1);
                app.timerange(2) = app.timerangelimit(2);

                % update other common fields
                updatecommonfeats(app);

            end


        end

        % Value changed function: CommonStart
        function CommonStartValueChanged(app, event)
            value = event.Value;

            % Value can't be less than Start or greater than End
            if value < app.timerangelimit(1)
                % error message that value can't be less than common start
                app.CommonStart.Value = app.timerange(1);
            elseif value >= app.timerangelimit(2)
                % error message that value can't be equal to or greater
                % than common end
                app.CommonStart.Value = app.timerange(1);
            else
                app.timerange(1) = value;
            end
            updatecommonfeats(app)
        end

        % Value changed function: CommonEnd
        function CommonEndValueChanged(app, event)
            value = event.Value;

            % Value can't be less than Start or greater than End
            if value <= app.timerangelimit(1)
                % error message that value can't be less than common start
                app.CommonEnd.Value = app.timerange(2);
            elseif value > app.timerangelimit(2)
                % error message that value can't be equal to or greater
                % than common end
                app.CommonEnd.Value = app.timerange(2);
            else
                app.timerange(2) = value;
            end
            updatecommonfeats(app)
        end

        % Callback function
        function GroupingDropDownOpening(app, event)

        end

        % Value changed function: GroupingDropDown
        function GroupingDropDownValueChanged(app, event)
            value = app.GroupingDropDown.Value;
            app.plotgrouping = value;
        end

        % Callback function
        function ControlDropDownOpening(app, event)
            app.ControlDropDown.Items = app.genos2plot;
        end

        % Value changed function: ControlDropDown
        function ControlDropDownValueChanged(app, event)
            value = app.ControlDropDown.Value;
            app.plotcontrol = value;
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            value = app.GraphDropDown.Value;
            app.graphtype = value;
        end

        % Value changed function: StatisticsDropDown
        function StatisticsDropDownValueChanged(app, event)
            value = app.StatisticsDropDown.Value;
            app.stats = value;
        end

        % Value changed function: UpdatematfilesCheckBox
        function UpdatematfilesCheckBoxValueChanged(app, event)
            value = app.UpdatematfilesCheckBox.Value;
            app.updatemat = value;
        end

        % Value changed function: PlotAbsDifffromControlCheckBox
        function PlotAbsDifffromControlCheckBoxValueChanged(app, event)
            value = app.PlotAbsDifffromControlCheckBox.Value;
            app.absdiff = value;
        end

        % Value changed function: ExportdatatoExcelCheckBox
        function ExportdatatoExcelCheckBoxValueChanged(app, event)
            value = app.ExportdatatoExcelCheckBox.Value;
            app.export2excel = value;
            app.xlsSavePath = cd;
            app.SavePathEditField.Value = app.xlsSavePath;
        end

        % Value changed function: BinningDropDown
        function BinningDropDownValueChanged(app, event)
            value = app.BinningDropDown.Value;
            app.plotbinning = value;
        end

        % Button pushed function: PlotBox
        function PlotBoxPushed(app, event)
            % This will produce 1 plot per metric chosen

            %params.genotype
            %params.files
            %params.metric = {'bout_freq','bout_dur','total_time','bout_rate',...
            %'bout_dur','
            %params.stat_test = {'Friedman','T-test','ranksum'}
            %params.posthoc = {'bonferroni','multcompare'}
            %params.comparison_matrix = {'true','false'}; %true = compare behaviors
            %against each other within a genotype, false = do not

            % GET FILES TO ANALYZE

            % data for plots
            genos2plot = app.genos2plot;
            %             filecounts = length(app.genos2plot);
            %             genotypes = {};
            %
            %             for i = 1:length(app.data)
            %                 genotypes{i} = app.data{i}.genotype;
            %             end

            % If control is specified...
            if contains(app.plotcontrol,'None')
                con = 'None';
            else
                con = app.plotcontrol;
                % reorder genotypes with control first
                conind = find(contains(genos2plot,con)==1);
                sort_order = [conind setdiff(1:length(genos2plot),conind)];
                genos2plot = genos2plot(sort_order);

            end

            plotdata = data2table(app);

            plotboxchart(app,plotdata);


        end

        % Value changed function: StimREditField
        function StimREditFieldValueChanged(app, event)
            value = app.StimREditField.Value;
            app.stimcolor(1) = value;
            app.StimColorLamp.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Value changed function: StimGEditField
        function StimGEditFieldValueChanged(app, event)
            value = app.StimGEditField.Value;
            app.stimcolor(2) = value;
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Value changed function: StimBEditField
        function StimBEditFieldValueChanged(app, event)
            value = app.StimBEditField.Value;
            app.stimcolor(3) = value;
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Value changed function: PlotStimulusCheckBox
        function PlotStimulusCheckBoxValueChanged(app, event)
            value = app.PlotStimulusCheckBox.Value;
            app.plotstim = value;
            if value ==1
                app.stimtimes.Enable = 'on';
            else
                app.stimtimes.Enable = 'off';
            end
            %             for i = 1:length(app)
            %             stim_mat = matrixconversion_test_new(app);
            %             end

        end

        % Cell edit callback: stimtimes
        function stimtimesCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: PlotHistogram
        function PlotHistogramButtonPushed(app, event)
            plothistogram(app)

        end

        % Value changed function: GenotypestoplotList
        function GenotypestoplotListHistValueChanged(app, event)
            value = event.Value;
            app.genos2plot = value;
            app.genoIdx = find(~cellfun(@isempty,cellfun(@(x)...
                find(strcmp(x,app.genos2plot)>0),app.genotypes,'uniformoutput',false)) == 1); % Update index of which genotype is selected
            app.genoIdx
        end

        % Value changed function: BehaviorstoplotList
        function BehaviorstoplotListHistValueChanged(app, event)
            value = event.Value;
            app.behavs2plot = value;
            app.behavIdx = find(~cellfun(@isempty,cellfun(@(x)...
                find(strcmp(x,app.behavs2plot)>0),app.behaviors,'uniformoutput',false)) == 1);
            app.behavIdx% Update index of which genotype is selected
        end

        % Button pushed function: LoadParamsButton
        function LoadParamsButtonPushed(app, event)
            [bfileName,bdirName] = uigetfile({'*.csv','csv files (*.csv)'}, 'Select behavior param csv file');
            [behaviorClasses B BList] = import_behavparams(bdirName,bfileName);
            app.behaviorClasses = behaviorClasses;
            app.behavTable = B;
            app.behavList = BList;
            app.LoadParamsButton.BackgroundColor = [0 1 0];

        end

        % Button pushed function: PlotEthogram
        function PlotEthogramButtonPushed(app, event)
            plot_ethogram(app)
        end

        % Value changed function: EthogramtypeDropDown
        function EthogramtypeDropDownValueChanged(app, event)
            value = app.EthogramtypeDropDown.Value;
            app.ethogram_type = value;
        end

        % Value changed function: StimREditField_etho
        function StimREditField_ethoValueChanged(app, event)
            value = app.StimREditField_etho.Value;
            app.stimcolor(1) = value;
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Value changed function: StimBEditField_etho
        function StimBEditField_ethoValueChanged(app, event)
            value = app.StimBEditField_etho.Value;
            app.stimcolor(3) = value;
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Value changed function: StimGEditField_etho
        function StimGEditField_ethoValueChanged(app, event)
            value = app.StimGEditField_etho.Value;
            app.stimcolor(2) = value;
            app.StimColorLamp.Color = app.stimcolor;
            app.StimColorLamp_etho.Color = app.stimcolor;
            update_stimcolors(app);
        end

        % Drop down opening function: EthogramtypeDropDown
        function EthogramtypeDropDownOpening(app, event)
            app.ethogram_type = 'Compact';
        end

        % Value changed function: PlotStimulusCheckBox_etho
        function PlotStimulusCheckBox_ethoValueChanged(app, event)
            value = app.PlotStimulusCheckBox_etho.Value;
            app.plotstim = value;
            if value ==1
                app.stimtimes.Enable = 'on';
            else
                app.stimtimes.Enable = 'off';
            end

        end

        % Callback function
        function MetricDropDownValueChanged(app, event)
            value = app.MetricDropDown.Value;
            app.plotmetrics = value;
        end

        % Value changed function: BoutFrequencyCheckBox
        function BoutFrequencyCheckBoxValueChanged(app, event)
            value = app.BoutFrequencyCheckBox.Value;

            if value==1 % add to metrics
                if isempty(app.plotmetrics)
                    app.plotmetrics = {'Freq'};
                else
                    app.plotmetrics = [app.plotmetrics 'Freq'];
                end

            elseif value==0 % remove from metrics
                ind = contains(app.plotmetrics,'Freq');
                app.plotmetrics = app.plotmetrics(~ind);
            end
        end

        % Value changed function: BoutDurationCheckBox
        function BoutDurationCheckBoxValueChanged(app, event)
            value = app.BoutDurationCheckBox.Value;

            if value==1 % add to metrics
                if isempty(app.plotmetrics)
                    app.plotmetrics = {'Dur'};
                else
                    app.plotmetrics = [app.plotmetrics 'Dur'];
                end

            elseif value==0 % remove from metrics
                ind = contains(app.plotmetrics,'Dur');
                app.plotmetrics = app.plotmetrics(~ind);
            end
        end

        % Value changed function: PercentTimeCheckBox
        function PercentTimeCheckBoxValueChanged(app, event)
            value = app.PercentTimeCheckBox.Value;

            if value==1 % add to metrics
                if isempty(app.plotmetrics)
                    app.plotmetrics = {'Percent_time'};
                else
                    app.plotmetrics = [app.plotmetrics 'Percent_time'];
                end

            elseif value==0 % remove from metrics
                ind = contains(app.plotmetrics,'Percent_time');
                app.plotmetrics = app.plotmetrics(~ind);
            end
        end

        % Value changed function: TotalTimeCheckBox
        function TotalTimeCheckBoxValueChanged(app, event)
            value = app.TotalTimeCheckBox.Value;

            if value==1 % add to metrics
                if isempty(app.plotmetrics)
                    app.plotmetrics = {'Total_time'};
                else
                    app.plotmetrics = [app.plotmetrics 'Total_time'];
                end

            elseif value==0 % remove from metrics
                ind = contains(app.plotmetrics,'Total_time');
                app.plotmetrics = app.plotmetrics(~ind);
            end

        end

        % Callback function
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            badChar = '.<>:"/\|?*';
            bad = ismember(BadChar, value);
            if any(bad)
                app.FilenameEditField.FontColor = [1 0 0];
            else
                app.FilenameEditField.FontName = [0 1 0];
            end
            

        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            xlspath = uigetdir(cd,'Select where to save the xls file.');
            if isscalar(xlspath)
                return % the user cancelled
            end
            app.xlsSavePath = xlspath;
            app.SavePathEditField.Value = app.xlsSavePath;
        end

        % Button pushed function: ProcesstxtFilesButton
        function ProcesstxtFilesButtonPushed(app, event)
            process_grooming;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotselectionPanel = uipanel(app.UIFigure);
            app.PlotselectionPanel.Title = 'Plot selection';
            app.GenotypestoplotListBoxLabel = uilabel(app.PlotselectionPanel);
            app.GenotypestoplotListBoxLabel.Text = 'Genotypes to plot';
            app.GenotypestoplotList = uilistbox(app.PlotselectionPanel);
            app.GenotypestoplotList.Items = {};
            app.GenotypestoplotList.Multiselect = 'on';
            app.GenotypestoplotList.ValueChangedFcn = createCallbackFcn(app, @GenotypestoplotListHistValueChanged, true);
            app.GenotypestoplotList.Value = {};
            app.BehaviorstoplotListBoxLabel = uilabel(app.PlotselectionPanel);
            app.BehaviorstoplotListBoxLabel.Text = 'Behaviors to plot';
            app.BehaviorstoplotList = uilistbox(app.PlotselectionPanel);
            app.BehaviorstoplotList.Items = {};
            app.BehaviorstoplotList.Multiselect = 'on';
            app.BehaviorstoplotList.ValueChangedFcn = createCallbackFcn(app, @BehaviorstoplotListHistValueChanged, true);
            app.BehaviorstoplotList.Value = {};
            app.EditParamsButton = uibutton(app.PlotselectionPanel, 'push');
            app.EditParamsButton.Enable = 'off';
            app.EditParamsButton.Visible = 'off';
            app.EditParamsButton.Text = 'Edit Params';
            app.LoadParamsButton = uibutton(app.PlotselectionPanel, 'push');
            app.LoadParamsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParamsButtonPushed, true);
            app.LoadParamsButton.Text = 'Load Params';
            app.CommonStart = uieditfield(app.PlotselectionPanel, 'numeric');
            app.CommonStart.ValueChangedFcn = createCallbackFcn(app, @CommonStartValueChanged, true);
            app.CommonStartLabel = uilabel(app.PlotselectionPanel);
            app.CommonStartLabel.Text = 'Start';
            app.CommonEnd = uieditfield(app.PlotselectionPanel, 'numeric');
            app.CommonEnd.ValueChangedFcn = createCallbackFcn(app, @CommonEndValueChanged, true);
            app.CommonEndLabel = uilabel(app.PlotselectionPanel);
            app.CommonEndLabel.Text = 'End';
            app.TimeRangesecLabel_2 = uilabel(app.PlotselectionPanel);
            app.TimeRangesecLabel_2.Text = 'Time Range (sec)';
            app.UITable = uitable(app.PlotselectionPanel);
            app.UITable.ColumnName = {'Start'; 'End'};
            app.UITable.RowName = {};
            app.MatFilesPanel = uipanel(app.UIFigure);
            app.MatFilesPanel.Title = 'Mat Files';
            app.GenotypeEditFieldLabel = uilabel(app.MatFilesPanel);
            app.GenotypeEditFieldLabel.Text = 'Genotype';
            app.GenotypeEditField = uieditfield(app.MatFilesPanel, 'text');
            app.GenotypeEditField.ValueChangedFcn = createCallbackFcn(app, @GenotypeEditFieldValueChanged, true);
            app.StartLabel = uilabel(app.MatFilesPanel);
            app.StartLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.MatFilesPanel, 'numeric');
            app.EndEditFieldLabel = uilabel(app.MatFilesPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.MatFilesPanel, 'numeric');
            app.TimeRangesecLabel = uilabel(app.MatFilesPanel);
            app.TimeRangesecLabel.Text = 'Time Range (sec)';
            app.BehaviorsListBoxLabel = uilabel(app.MatFilesPanel);
            app.BehaviorsListBoxLabel.Text = 'Behaviors';
            app.BehaviorsListBox = uilistbox(app.MatFilesPanel);
            app.BehaviorsListBox.Items = {};
            app.BehaviorsListBox.Value = {};
            app.AddfilesButton = uibutton(app.MatFilesPanel, 'push');
            app.AddfilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddfilesButtonPushed, true);
            app.AddfilesButton.Text = 'Add files';
            app.FilesListBoxLabel = uilabel(app.MatFilesPanel);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.MatFilesPanel);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.RemovefilesButton = uibutton(app.MatFilesPanel, 'push');
            app.RemovefilesButton.ButtonPushedFcn = createCallbackFcn(app, @RemovefilesButtonPushed, true);
            app.RemovefilesButton.Enable = 'off';
            app.RemovefilesButton.Visible = 'off';
            app.RemovefilesButton.Text = 'Remove files';
            app.ProcesstxtFilesButton = uibutton(app.MatFilesPanel, 'push');
            app.ProcesstxtFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesstxtFilesButtonPushed, true);
            app.ProcesstxtFilesButton.Text = 'Process txt Files';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BoxplotsTab = uitab(app.TabGroup);
            app.BoxplotsTab.Title = 'Boxplots';
            app.StatisticsDropDownLabel = uilabel(app.BoxplotsTab);
            app.StatisticsDropDownLabel.Enable = 'off';
            app.StatisticsDropDownLabel.Text = 'Statistics';
            app.StatisticsDropDown = uidropdown(app.BoxplotsTab);
            app.StatisticsDropDown.Items = {'None', 'Parametric', 'Non-parametric'};
            app.StatisticsDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticsDropDownValueChanged, true);
            app.StatisticsDropDown.Enable = 'off';
            app.StatisticsDropDown.Value = 'None';
            app.GroupingDropDownLabel = uilabel(app.BoxplotsTab);
            app.GroupingDropDownLabel.Text = 'Grouping';
            app.GroupingDropDown = uidropdown(app.BoxplotsTab);
            app.GroupingDropDown.Items = {'Behavior', 'Genotype'};
            app.GroupingDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupingDropDownValueChanged, true);
            app.GroupingDropDown.Value = 'Behavior';
            app.UpdatematfilesCheckBox = uicheckbox(app.BoxplotsTab);
            app.UpdatematfilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatematfilesCheckBoxValueChanged, true);
            app.UpdatematfilesCheckBox.Enable = 'off';
            app.UpdatematfilesCheckBox.Text = 'Update mat files';
            app.ControlDropDownLabel = uilabel(app.BoxplotsTab);
            app.ControlDropDownLabel.Text = 'Control';
            app.ControlDropDown = uidropdown(app.BoxplotsTab);
            app.ControlDropDown.Items = {'None'};
            app.ControlDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlDropDownValueChanged, true);
            app.ControlDropDown.Value = 'None';
            app.BinningDropDownLabel = uilabel(app.BoxplotsTab);
            app.BinningDropDownLabel.Enable = 'off';
            app.BinningDropDownLabel.Text = 'Binning';
            app.BinningDropDown = uidropdown(app.BoxplotsTab);
            app.BinningDropDown.Items = {'Total', 'Binned'};
            app.BinningDropDown.ValueChangedFcn = createCallbackFcn(app, @BinningDropDownValueChanged, true);
            app.BinningDropDown.Enable = 'off';
            app.BinningDropDown.Value = 'Total';
            app.PlotAbsDifffromControlCheckBox = uicheckbox(app.BoxplotsTab);
            app.PlotAbsDifffromControlCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotAbsDifffromControlCheckBoxValueChanged, true);
            app.PlotAbsDifffromControlCheckBox.Tag = 'abs_diff';
            app.PlotAbsDifffromControlCheckBox.Enable = 'off';
            app.PlotAbsDifffromControlCheckBox.Text = 'Plot Abs. Diff. from Control';
            app.GraphDropDownLabel = uilabel(app.BoxplotsTab);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.BoxplotsTab);
            app.GraphDropDown.Items = {'Box plot'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Value = 'Box plot';
            app.ExportdatatoExcelCheckBox = uicheckbox(app.BoxplotsTab);
            app.ExportdatatoExcelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportdatatoExcelCheckBoxValueChanged, true);
            app.ExportdatatoExcelCheckBox.Text = 'Export data to Excel';
            app.PlotBox = uibutton(app.BoxplotsTab, 'push');
            app.PlotBox.ButtonPushedFcn = createCallbackFcn(app, @PlotBoxPushed, true);
            app.PlotBox.Text = {'Plot'; ''};
            app.BoutFrequencyCheckBox = uicheckbox(app.BoxplotsTab);
            app.BoutFrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoutFrequencyCheckBoxValueChanged, true);
            app.BoutFrequencyCheckBox.Text = 'Bout Frequency';
            app.BoutDurationCheckBox = uicheckbox(app.BoxplotsTab);
            app.BoutDurationCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoutDurationCheckBoxValueChanged, true);
            app.BoutDurationCheckBox.Text = 'Bout Duration';
            app.PercentTimeCheckBox = uicheckbox(app.BoxplotsTab);
            app.PercentTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PercentTimeCheckBoxValueChanged, true);
            app.PercentTimeCheckBox.Text = 'Percent Time';
            app.TotalTimeCheckBox = uicheckbox(app.BoxplotsTab);
            app.TotalTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TotalTimeCheckBoxValueChanged, true);
            app.TotalTimeCheckBox.Text = 'Total Time';
            app.MetricBox = uilabel(app.BoxplotsTab);
            app.MetricBox.Text = 'Metric';
            app.SetButton = uibutton(app.BoxplotsTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.SavePathEditFieldLabel = uilabel(app.BoxplotsTab);
            app.SavePathEditFieldLabel.Text = 'Save Path:';
            app.SavePathEditField = uieditfield(app.BoxplotsTab, 'text');
            app.HistogramsTab = uitab(app.TabGroup);
            app.HistogramsTab.Title = 'Histograms';
            app.FrameRatefpsEditFieldLabel = uilabel(app.HistogramsTab);
            app.FrameRatefpsEditFieldLabel.Text = 'Frame Rate (fps)';
            app.FrameRatefpsEditField = uieditfield(app.HistogramsTab, 'numeric');
            app.FrameRatefpsEditField.Value = 30;
            app.SmoothingsecEditFieldLabel = uilabel(app.HistogramsTab);
            app.SmoothingsecEditFieldLabel.Text = 'Smoothing (sec)';
            app.SmoothingsecEditField = uieditfield(app.HistogramsTab, 'numeric');
            app.SmoothingsecEditField.Value = 1;
            app.StimulusPanel = uipanel(app.HistogramsTab);
            app.StimulusPanel.Title = 'Stimulus';
            app.PlotStimulusCheckBox = uicheckbox(app.StimulusPanel);
            app.PlotStimulusCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotStimulusCheckBoxValueChanged, true);
            app.PlotStimulusCheckBox.Text = 'Plot Stimulus';
            app.stimtimes = uitable(app.StimulusPanel);
            app.stimtimes.ColumnName = {'Time'; 'Dur'};
            app.stimtimes.RowName = {'1'; ''};
            app.stimtimes.ColumnEditable = [true true true];
            app.stimtimes.CellEditCallback = createCallbackFcn(app, @stimtimesCellEdit, true);
            app.stimtimes.Enable = 'off';
            app.StimulustimessecLabel = uilabel(app.StimulusPanel);
            app.StimulustimessecLabel.Enable = 'off';
            app.StimulustimessecLabel.Text = 'Stimulus times (sec)';
            app.REditFieldLabel = uilabel(app.StimulusPanel);
            app.REditFieldLabel.Text = 'R';
            app.StimREditField = uieditfield(app.StimulusPanel, 'numeric');
            app.StimREditField.Limits = [0 1];
            app.StimREditField.ValueChangedFcn = createCallbackFcn(app, @StimREditFieldValueChanged, true);
            app.GEditFieldLabel = uilabel(app.StimulusPanel);
            app.GEditFieldLabel.Text = 'G';
            app.StimGEditField = uieditfield(app.StimulusPanel, 'numeric');
            app.StimGEditField.Limits = [0 1];
            app.StimGEditField.ValueChangedFcn = createCallbackFcn(app, @StimGEditFieldValueChanged, true);
            app.BEditFieldLabel = uilabel(app.StimulusPanel);
            app.BEditFieldLabel.Text = 'B';
            app.StimBEditField = uieditfield(app.StimulusPanel, 'numeric');
            app.StimBEditField.Limits = [0 1];
            app.StimBEditField.ValueChangedFcn = createCallbackFcn(app, @StimBEditFieldValueChanged, true);
            app.StimColorLamp = uilamp(app.StimulusPanel);
            app.StimulusColorLabel = uilabel(app.StimulusPanel);
            app.StimulusColorLabel.Text = 'Stimulus Color';
            app.addStim = uibutton(app.StimulusPanel, 'push');
            app.addStim.Enable = 'off';
            app.addStim.Text = '+';
            app.removeStim = uibutton(app.StimulusPanel, 'push');
            app.removeStim.Enable = 'off';
            app.removeStim.Text = '-';
            app.PlotHistogram = uibutton(app.HistogramsTab, 'push');
            app.PlotHistogram.ButtonPushedFcn = createCallbackFcn(app, @PlotHistogramButtonPushed, true);
            app.PlotHistogram.Text = {'Plot'; ''};
            app.EthogramsTab = uitab(app.TabGroup);
            app.EthogramsTab.Title = 'Ethograms';
            app.PlotEthogram = uibutton(app.EthogramsTab, 'push');
            app.PlotEthogram.ButtonPushedFcn = createCallbackFcn(app, @PlotEthogramButtonPushed, true);
            app.PlotEthogram.Text = {'Plot'; ''};
            app.EthogramtypeDropDownLabel = uilabel(app.EthogramsTab);
            app.EthogramtypeDropDownLabel.Text = 'Ethogram type:';
            app.EthogramtypeDropDown = uidropdown(app.EthogramsTab);
            app.EthogramtypeDropDown.Items = {'Compact', 'Expanded'};
            app.EthogramtypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @EthogramtypeDropDownOpening, true);
            app.EthogramtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EthogramtypeDropDownValueChanged, true);
            app.EthogramtypeDropDown.Value = 'Compact';
            app.PlotStimulusCheckBox_etho = uicheckbox(app.EthogramsTab);
            app.PlotStimulusCheckBox_etho.ValueChangedFcn = createCallbackFcn(app, @PlotStimulusCheckBox_ethoValueChanged, true);
            app.PlotStimulusCheckBox_etho.Text = 'Plot Stimulus';
            app.StimulusColorLabel_etho = uilabel(app.EthogramsTab);
            app.StimulusColorLabel_etho.Text = 'Stimulus Color';
            app.StimColorLamp_etho = uilamp(app.EthogramsTab);
            app.REditFieldLabel_2 = uilabel(app.EthogramsTab);
            app.REditFieldLabel_2.Text = 'R';
            app.StimREditField_etho = uieditfield(app.EthogramsTab, 'numeric');
            app.StimREditField_etho.Limits = [0 1];
            app.StimREditField_etho.ValueChangedFcn = createCallbackFcn(app, @StimREditField_ethoValueChanged, true);
            app.GEditFieldLabel_2 = uilabel(app.EthogramsTab);
            app.GEditFieldLabel_2.Text = 'G';
            app.StimGEditField_etho = uieditfield(app.EthogramsTab, 'numeric');
            app.StimGEditField_etho.Limits = [0 1];
            app.StimGEditField_etho.ValueChangedFcn = createCallbackFcn(app, @StimGEditField_ethoValueChanged, true);
            app.BEditFieldLabel_2 = uilabel(app.EthogramsTab);
            app.BEditFieldLabel_2.Text = 'B';
            app.StimBEditField_etho = uieditfield(app.EthogramsTab, 'numeric');
            app.StimBEditField_etho.Limits = [0 1];
            app.StimBEditField_etho.ValueChangedFcn = createCallbackFcn(app, @StimBEditField_ethoValueChanged, true);
            app.FlyGroomAFlyGroomingAnalysisLabel = uilabel(app.UIFigure);
            app.FlyGroomAFlyGroomingAnalysisLabel.Text = 'FlyGroomA - Fly Grooming Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlyGroomA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end